classdef GameGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ROLLButton                      matlab.ui.control.Button
        Player1ScoreEditFieldLabel      matlab.ui.control.Label
        Player1ScoreEditField           matlab.ui.control.NumericEditField
        Play1PreviousRollsListBoxLabel  matlab.ui.control.Label
        Play1PreviousRollsListBox       matlab.ui.control.ListBox
        THISROLLEditFieldLabel          matlab.ui.control.Label
        THISROLLEditField               matlab.ui.control.EditField
        HTML                            matlab.ui.control.HTML
        Player2ScoreEditFieldLabel      matlab.ui.control.Label
        Player2ScoreEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        game = DiceGame(1); % game object
        diceRecord = [];    % keep record of all numbers rolled to get points for each round
    end
    
    methods (Access = private)
        
        function displayDice(app, points)
            str = app.game.getDiceStr();
            app.THISROLLEditField.Value = str + "    ==>    " + string(points);
            app.pushToQueue(str);
        end
        
        function pushToQueue(app, str)
            app.Play1PreviousRollsListBox.Items{end+1} = char(str);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            app.Play1PreviousRollsListBox.Items = {};
            app.Player1ScoreEditField.Value = 0;
        end

        % Button pushed function: ROLLButton
        function ROLLButtonPushed(app, event)
            % roll all the dice
            app.game = app.game.rollAllDice();
            % add a random number to an random index
            i = randi([1,5],1,1);
            num = randi([-10,10],1,1);
            app.game = app.game.setDieFront(i, num + app.game.getDice(i).getFront());  
            % push back the newly rolled 5 dice to the record
            app.diceRecord = [app.diceRecord app.game.getDiceFronts()];
            % calculate the points get from current record
            points = lengthOfLIS(app.diceRecord);
            otherPlayerPoint = randi([1,10],1,1);   % currently use random as place holder, will get this from server (ThingSpeak) in the future
            if (points > otherPlayerPoint)
                app.Player1ScoreEditField.Value = app.Player1ScoreEditField.Value + points;
            end
            app.Player2ScoreEditField.Value = otherPlayerPoint;   % will get this from server in the future
            % show the result in GUI
            app.displayDice(points);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ROLLButton = uibutton(app.UIFigure, 'push');
            app.ROLLButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLButtonPushed, true);
            app.ROLLButton.Text = 'ROLL!';
            app.Player1ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.Player1ScoreEditFieldLabel.Text = 'Player 1 Score:';
            app.Player1ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.Play1PreviousRollsListBoxLabel = uilabel(app.UIFigure);
            app.Play1PreviousRollsListBoxLabel.Text = 'Play 1 Previous Rolls';
            app.Play1PreviousRollsListBox = uilistbox(app.UIFigure);
            app.THISROLLEditFieldLabel = uilabel(app.UIFigure);
            app.THISROLLEditFieldLabel.Text = 'THIS ROLL!';
            app.THISROLLEditField = uieditfield(app.UIFigure, 'text');
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<center><h1> Load On Dice Game</h1></center>';
            app.Player2ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.Player2ScoreEditFieldLabel.Text = 'Player 2 Score:';
            app.Player2ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GameGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end